classdef ResponseTimeAndEnergyConsump < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        WorkloadMbSliderLabel           matlab.ui.control.Label
        WorkloadMbSlider                matlab.ui.control.Slider
        BandwidthButtonGroup            matlab.ui.container.ButtonGroup
        MHzButton                       matlab.ui.control.RadioButton
        MHzButton_2                     matlab.ui.control.RadioButton
        MHzButton_3                     matlab.ui.control.RadioButton
        MHzButton_4                     matlab.ui.control.RadioButton
        AdjustWiFi80211axparametersforMECLabel  matlab.ui.control.Label
        SpatialstreamsButtonGroup       matlab.ui.container.ButtonGroup
        SISOButton                      matlab.ui.control.RadioButton
        MIMO2x2Button                   matlab.ui.control.RadioButton
        MIMO3x3Button                   matlab.ui.control.RadioButton
        MIMO4x4Button                   matlab.ui.control.RadioButton
        MIMO6x6Button                   matlab.ui.control.RadioButton
        MIMO8x8Button                   matlab.ui.control.RadioButton
        ModulationButtonGroup           matlab.ui.container.ButtonGroup
        BPSKButton                      matlab.ui.control.RadioButton
        QPSKButton                      matlab.ui.control.RadioButton
        QAM16Button                     matlab.ui.control.RadioButton
        QAM64Button                     matlab.ui.control.RadioButton
        QAM256Button                    matlab.ui.control.RadioButton
        QAM1024Button                   matlab.ui.control.RadioButton
        BandwidthButtonGroup_2          matlab.ui.container.ButtonGroup
        fiveMHzButton                   matlab.ui.control.RadioButton
        tenMHzButton                    matlab.ui.control.RadioButton
        fifteenMHzButton                matlab.ui.control.RadioButton
        thirtyMHzButton                 matlab.ui.control.RadioButton
        fourtyMHzButton                 matlab.ui.control.RadioButton
        fiftyMHzButton                  matlab.ui.control.RadioButton
        sixtyMHzButton                  matlab.ui.control.RadioButton
        eghtyMHzButton                  matlab.ui.control.RadioButton
        ninetyMHzButton                 matlab.ui.control.RadioButton
        hundredMHzButton                matlab.ui.control.RadioButton
        twentyMHzButton                 matlab.ui.control.RadioButton
        twentyfiveMHzButton             matlab.ui.control.RadioButton
        seventyMHzButton                matlab.ui.control.RadioButton
        Adjust5GNRparametersforMCCLabel  matlab.ui.control.Label
        SpatialstreamsButtonGroup_2     matlab.ui.container.ButtonGroup
        SISOButton_2                    matlab.ui.control.RadioButton
        MIMO2x2Button_2                 matlab.ui.control.RadioButton
        MIMO3x3Button_2                 matlab.ui.control.RadioButton
        MIMO4x4Button_2                 matlab.ui.control.RadioButton
        MIMO6x6Button_2                 matlab.ui.control.RadioButton
        MIMO8x8Button_2                 matlab.ui.control.RadioButton
        ModulationButtonGroup_2         matlab.ui.container.ButtonGroup
        QPSKButton_2                    matlab.ui.control.RadioButton
        QAM16Button_2                   matlab.ui.control.RadioButton
        QAM64Button_2                   matlab.ui.control.RadioButton
        QAM256Button_2                  matlab.ui.control.RadioButton
        IoTprocessorfrequencyEditFieldLabel  matlab.ui.control.Label
        IoTprocessorfrequencyEditField  matlab.ui.control.NumericEditField
        x36GHzLabel                     matlab.ui.control.Label
        EdgeserverprocessorfrequencyEditFieldLabel  matlab.ui.control.Label
        EdgeserverprocessorfrequencyEditField  matlab.ui.control.NumericEditField
        x30GHzLabel                     matlab.ui.control.Label
        CloudserverprocessorfrequencyEditFieldLabel  matlab.ui.control.Label
        CloudserverprocessorfrequencyEditField  matlab.ui.control.NumericEditField
        x47GHzLabel                     matlab.ui.control.Label
        OutputresultsLabel              matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        OverallresponsetimemsLabel      matlab.ui.control.Label
        UITable2                        matlab.ui.control.Table
        OverallpowerconsumptionkWhLabel  matlab.ui.control.Label
        UITable2_2                      matlab.ui.control.Table
        BitrateMbpsLabel                matlab.ui.control.Label
        UITable2_3                      matlab.ui.control.Table
        UIAxesTIot                      matlab.ui.control.UIAxes
        UIAxesEIot                      matlab.ui.control.UIAxes
        UIAxesTEdge                     matlab.ui.control.UIAxes
        UIAxesEEdge                     matlab.ui.control.UIAxes
        UIAxesTCloud                    matlab.ui.control.UIAxes
        UIAxesECloud                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Workload % Description
        NumberSD %Numver of Data Subcarriers for Wi-Fi
        NumberSS %Number of spatial streams for Wi-Fi
        ModulationCoefficient % number of bits in signal unit based on the modulation in Wi-Fi
        NumberRB %Number of resource blocks for 5G
        ModulationCoefficient2 % number of bits in signal unit based on the modulation in 5G
        NumberSS_2 %Number of spatial streams for 5G
        %numberiotCPU % IoT server processor frequency in Hz
        %numberedgeCPU %MEC server ptocessor frequency in Hz
        %numbercloudCPU %MCC server processor frequency in Hz
    end

    
    methods (Access = private)
        
        function plotUpdate(app)
            w = app.Workload*10^6; %bit
            % IoT power consumption
            p_idle = 0.001; %W - IoT idle power, i.o. when the computing located outside the device
            p_ex = 0.08; %W - CPU computational power of IoT device
            p_tr = 0.085; %W - data transmission power of IoT device
            %IoT model
            f_iot = 8*3600000000;% Hz
            T_comp_iot = w./f_iot*1000; %in [ms]
            T_iot = T_comp_iot;
            E_comp_iot = p_ex*T_comp_iot*3.6; % E_iot - energy consumption when computing in IoT device
            E_iot = E_comp_iot;
            %MEC model
            f_mec = 16 * 4700000000; %Hz
            T_comp_mec = w./f_mec*1000;% in [ms]
            %Calculating Wi-Fi bit rate
            N_sd = app.NumberSD;
            N_ss = app.NumberSS;
            Q = app.ModulationCoefficient;
            t = 0.000014; % sum of OFDM symbol duration (12.8*10^(-6)s) and Guard interval duration (medium - 1.2*10^(-6)s)
            r = 3/4; %coding
            C_mec = N_sd*N_ss*r*Q/(t*10^6); % wifi bit rate in [Mbps]
            T_comm_mec = 10^(-6)*w./C_mec; %communication time Wi-Fi
            T_mec = T_comp_mec+T_comm_mec;
            E_comp_mec = p_idle*T_comp_mec*3.6;
            E_comm_mec = p_tr*T_comm_mec*3.6;
            E_mec = E_comp_mec+ E_comm_mec;
            %MCC model
            f_mcc = 96*3000000000; %Hz
            T_comp_mcc = w./f_mcc; % in [ms]
            % Calculating 5G NR bit rate
            N_rb = app.NumberRB;
            N_ss2 = app.NumberSS_2;
            Q2 = app.ModulationCoefficient2;
            %mu = 1; % numerology for subcarrier space 30kHz (in formula
            %2^mu)
            f = 0.75; %scaling factor
            r = 0.92578125; %max code rate
            %OH = 0.2; % max overhead (1-OH = 0.8)
            C_mcc = N_ss2*Q2*f*r*N_rb*12*14*2*0.8*1000/10^6; % 5G NR bit rate in [Mbps]
            T_comm_mcc = 10^(-6)*w./C_mcc; %communication time 5G
            T_mcc = T_comp_mcc+T_comm_mcc;
            E_comp_mcc = p_idle*T_comp_mcc*3.6;
            E_comm_mcc = p_tr*T_comm_mcc*3.6;
            E_mcc = E_comp_mcc+ E_comm_mcc;
            %plotting
            x=categorical({'Computing'});
            x=reordercats(x,{'Computing'});
            x1=categorical({'Computing', 'Communication'});
            x1=reordercats(x1,{'Computing', 'Communication'});
            T1=[T_comp_iot];
            E1=[E_comp_iot];
            T2=[T_comp_mec;T_comm_mec];
            E2=[E_comp_mec;E_comm_mec];
            T3=[T_comp_mcc;T_comm_mcc];
            E3=[E_comp_mcc;E_comm_mcc];
            bar(app.UIAxesTIot,x,T1);
            bar(app.UIAxesEIot,x,E1);
            bar(app.UIAxesTEdge,x1,T2);
            bar(app.UIAxesEEdge,x1,E2);
            bar(app.UIAxesTCloud,x1,T3);
            bar(app.UIAxesECloud,x1,E3);
            app.UITable.Data = {app.Workload};
            app.UITable2.Data = {T_iot,T_mec,T_mcc};
            app.UITable2_2.Data = {E_iot,E_mec,E_mcc};
            app.UITable2_3.Data = {C_mec,C_mcc};
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.NumberSD = 980;
            app.NumberSS = 4;
            app.ModulationCoefficient = 10;
            app.NumberRB = 217;
            app.ModulationCoefficient2 = 8;
            app.NumberSS_2 = 8;
        end

        % Value changed function: WorkloadMbSlider
        function WorkloadMbSliderValueChanged(app, event)
            app.Workload = app.WorkloadMbSlider.Value;
            plotUpdate(app);
        end

        % Selection changed function: BandwidthButtonGroup
        function BandwidthButtonGroupSelectionChanged(app, event)
            selectedButton1 = app.BandwidthButtonGroup.SelectedObject;
            
            if selectedButton1 == app.MHzButton
                app.NumberSD = 234;
                plotUpdate(app);
            elseif selectedButton1 == app.MHzButton_2
                app.NumberSD = 468;
                plotUpdate(app);
            elseif selectedButton1 == app.MHzButton_3
                app.NumberSD = 980;
                plotUpdate(app);
            elseif selectedButton1 == app.MHzButton_4
                app.NumberSD = 1960;
                plotUpdate(app);
            end
        end

        % Selection changed function: SpatialstreamsButtonGroup
        function SpatialstreamsButtonGroupSelectionChanged(app, event)
            selectedButton2 = app.SpatialstreamsButtonGroup.SelectedObject;
            
            if selectedButton2 == app.SISOButton
                app.NumberSS = 1;
                plotUpdate(app);
            elseif selectedButton2 == app.MIMO2x2Button
                app.NumberSS = 2;
                plotUpdate(app);
            elseif selectedButton2 == app.MIMO3x3Button
                app.NumberSS = 3;
                plotUpdate(app);
            elseif selectedButton2 == app.MIMO4x4Button
                app.NumberSS = 4;
                plotUpdate(app);
            elseif selectedButton2 == app.MIMO6x6Button
                app.NumberSS = 6;
                plotUpdate(app);
            elseif selectedButton2 == app.MIMO8x8Button
                app.NumberSS = 8;
                plotUpdate(app);
            end
        end

        % Selection changed function: ModulationButtonGroup
        function ModulationButtonGroupSelectionChanged(app, event)
            selectedButton3 = app.ModulationButtonGroup.SelectedObject;
            
            if selectedButton3 == app.BPSKButton
                app.ModulationCoefficient = 1;
                plotUpdate(app);
            elseif selectedButton3 == app.QPSKButton
                app.ModulationCoefficient = 2;
                plotUpdate(app);
            elseif selectedButton3 == app.QAM16Button
                app.ModulationCoefficient = 4;
                plotUpdate(app);
            elseif selectedButton3 == app.QAM64Button
                app.ModulationCoefficient = 6;
                plotUpdate(app);
            elseif selectedButton3 == app.QAM256Button
                app.ModulationCoefficient = 8;
                plotUpdate(app);
            elseif selectedButton3 == app.QAM1024Button
                app.ModulationCoefficient = 10;
                plotUpdate(app);
            end
        end

        % Selection changed function: BandwidthButtonGroup_2
        function BandwidthButtonGroup_2SelectionChanged(app, event)
            selectedButton4 = app.BandwidthButtonGroup_2.SelectedObject;
            
            if selectedButton4 == app.fiveMHzButton
                app.NumberRB = 11;
                plotUpdate(app);
            elseif selectedButton4 == app.tenMHzButton
                app.NumberRB = 24;
                plotUpdate(app);
            elseif selectedButton4 == app.fifteenMHzButton
                app.NumberRB = 38;
                plotUpdate(app);
            elseif selectedButton4 == app.twentyMHzButton
                app.NumberRB = 51;
                plotUpdate(app);
            elseif selectedButton4 == app.twentyfiveMHzButton
                app.NumberRB = 65;
                plotUpdate(app);    
            elseif selectedButton4 == app.thirtyMHzButton
                app.NumberRB = 78;
                plotUpdate(app);
            elseif selectedButton4 == app.fourtyMHzButton
                app.NumberRB = 106;
                plotUpdate(app);
            elseif selectedButton4 == app.fiftyMHzButton
                app.NumberRB = 133;
                plotUpdate(app);
            elseif selectedButton4 == app.sixtyMHzButton
                app.NumberRB = 162;
                plotUpdate(app);
            elseif selectedButton4 == app.seventyMHzButton
                app.NumberRB = 189;
                plotUpdate(app);    
            elseif selectedButton4 == app.eghtyMHzButton
                app.NumberRB = 217;
                plotUpdate(app);
            elseif selectedButton4 == app.ninetyMHzButton
                app.NumberRB = 245;
                plotUpdate(app);
            elseif selectedButton4 == app.hundredMHzButton
                app.NumberRB = 273;
                plotUpdate(app);
            
            end
        end

        % Selection changed function: ModulationButtonGroup_2
        function ModulationButtonGroup_2SelectionChanged(app, event)
            selectedButton5 = app.ModulationButtonGroup_2.SelectedObject;
            
            if selectedButton5 == app.QPSKButton_2
                app.ModulationCoefficient2 = 2;
                plotUpdate(app);
            elseif selectedButton5 == app.QAM16Button_2
                app.ModulationCoefficient2 = 4;
                plotUpdate(app);
            elseif selectedButton5 == app.QAM64Button_2
                app.ModulationCoefficient2 = 6;
                plotUpdate(app);
            elseif selectedButton5 == app.QAM256Button_2
                app.ModulationCoefficient2 = 8;
                plotUpdate(app);
            end
        end

        % Selection changed function: SpatialstreamsButtonGroup_2
        function SpatialstreamsButtonGroup_2SelectionChanged(app, event)
            selectedButton6 = app.SpatialstreamsButtonGroup_2.SelectedObject;
            
            if selectedButton6 == app.SISOButton_2
                app.NumberSS_2 = 1;
                plotUpdate(app);
            elseif selectedButton6 == app.MIMO2x2Button_2
                app.NumberSS_2 = 2;
                plotUpdate(app);
            elseif selectedButton6 == app.MIMO3x3Button_2
                app.NumberSS_2 = 3;
                plotUpdate(app);
            elseif selectedButton6 == app.MIMO4x4Button_2
                app.NumberSS_2 = 4;
                plotUpdate(app);
            elseif selectedButton6 == app.MIMO6x6Button_2
                app.NumberSS_2 = 6;
                plotUpdate(app);
            elseif selectedButton6 == app.MIMO8x8Button_2
                app.NumberSS_2 = 8;
                plotUpdate(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WorkloadMbSliderLabel = uilabel(app.UIFigure);
            app.WorkloadMbSliderLabel.Text = 'Workload [Mb]:';
            app.WorkloadMbSlider = uislider(app.UIFigure);
            app.WorkloadMbSlider.Limits = [1 100];
            app.WorkloadMbSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.WorkloadMbSlider.MajorTickLabels = {'0', '10', '20', '30', '40', '50', '60', '70', '80', '90', '100'};
            app.WorkloadMbSlider.ValueChangedFcn = createCallbackFcn(app, @WorkloadMbSliderValueChanged, true);
            app.WorkloadMbSlider.Value = 50;
            app.BandwidthButtonGroup = uibuttongroup(app.UIFigure);
            app.BandwidthButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BandwidthButtonGroupSelectionChanged, true);
            app.BandwidthButtonGroup.Title = 'Bandwidth:';
            app.MHzButton = uiradiobutton(app.BandwidthButtonGroup);
            app.MHzButton.Text = '20 MHz';
            app.MHzButton_2 = uiradiobutton(app.BandwidthButtonGroup);
            app.MHzButton_2.Text = '40 MHz';
            app.MHzButton_3 = uiradiobutton(app.BandwidthButtonGroup);
            app.MHzButton_3.Text = '80 MHz';
            app.MHzButton_3.Value = true;
            app.MHzButton_4 = uiradiobutton(app.BandwidthButtonGroup);
            app.MHzButton_4.Text = '160 MHz';
            app.AdjustWiFi80211axparametersforMECLabel = uilabel(app.UIFigure);
            app.AdjustWiFi80211axparametersforMECLabel.Text = 'Adjust WiFi 802.11ax parameters (for MEC):';
            app.SpatialstreamsButtonGroup = uibuttongroup(app.UIFigure);
            app.SpatialstreamsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SpatialstreamsButtonGroupSelectionChanged, true);
            app.SpatialstreamsButtonGroup.Title = 'Spatial streams:';
            app.SISOButton = uiradiobutton(app.SpatialstreamsButtonGroup);
            app.SISOButton.Text = 'SISO';
            app.MIMO2x2Button = uiradiobutton(app.SpatialstreamsButtonGroup);
            app.MIMO2x2Button.Text = 'MIMO 2x2';
            app.MIMO3x3Button = uiradiobutton(app.SpatialstreamsButtonGroup);
            app.MIMO3x3Button.Text = 'MIMO 3x3';
            app.MIMO4x4Button = uiradiobutton(app.SpatialstreamsButtonGroup);
            app.MIMO4x4Button.Text = 'MIMO 4x4';
            app.MIMO4x4Button.Value = true;
            app.MIMO6x6Button = uiradiobutton(app.SpatialstreamsButtonGroup);
            app.MIMO6x6Button.Text = 'MIMO 6x6';
            app.MIMO8x8Button = uiradiobutton(app.SpatialstreamsButtonGroup);
            app.MIMO8x8Button.Text = 'MIMO 8x8';
            app.ModulationButtonGroup = uibuttongroup(app.UIFigure);
            app.ModulationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModulationButtonGroupSelectionChanged, true);
            app.ModulationButtonGroup.Title = 'Modulation:';
            app.BPSKButton = uiradiobutton(app.ModulationButtonGroup);
            app.BPSKButton.Text = 'BPSK';
            app.QPSKButton = uiradiobutton(app.ModulationButtonGroup);
            app.QPSKButton.Text = 'QPSK';
            app.QAM16Button = uiradiobutton(app.ModulationButtonGroup);
            app.QAM16Button.Text = 'QAM-16';
            app.QAM64Button = uiradiobutton(app.ModulationButtonGroup);
            app.QAM64Button.Text = 'QAM-64';
            app.QAM256Button = uiradiobutton(app.ModulationButtonGroup);
            app.QAM256Button.Text = 'QAM-256';
            app.QAM1024Button = uiradiobutton(app.ModulationButtonGroup);
            app.QAM1024Button.Text = 'QAM-1024';
            app.QAM1024Button.Value = true;
            app.BandwidthButtonGroup_2 = uibuttongroup(app.UIFigure);
            app.BandwidthButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @BandwidthButtonGroup_2SelectionChanged, true);
            app.BandwidthButtonGroup_2.Title = 'Bandwidth:';
            app.fiveMHzButton = uiradiobutton(app.BandwidthButtonGroup_2);
            app.fiveMHzButton.Text = '5 MHz';
            app.tenMHzButton = uiradiobutton(app.BandwidthButtonGroup_2);
            app.tenMHzButton.Text = '10 MHz';
            app.fifteenMHzButton = uiradiobutton(app.BandwidthButtonGroup_2);
            app.fifteenMHzButton.Text = '15 MHz';
            app.thirtyMHzButton = uiradiobutton(app.BandwidthButtonGroup_2);
            app.thirtyMHzButton.Text = '30 MHz';
            app.fourtyMHzButton = uiradiobutton(app.BandwidthButtonGroup_2);
            app.fourtyMHzButton.Text = '40 MHz';
            app.fiftyMHzButton = uiradiobutton(app.BandwidthButtonGroup_2);
            app.fiftyMHzButton.Text = '50 MHz';
            app.sixtyMHzButton = uiradiobutton(app.BandwidthButtonGroup_2);
            app.sixtyMHzButton.Text = '60 MHz';
            app.eghtyMHzButton = uiradiobutton(app.BandwidthButtonGroup_2);
            app.eghtyMHzButton.Text = '80 MHz';
            app.eghtyMHzButton.Value = true;
            app.ninetyMHzButton = uiradiobutton(app.BandwidthButtonGroup_2);
            app.ninetyMHzButton.Text = '90 MHz';
            app.hundredMHzButton = uiradiobutton(app.BandwidthButtonGroup_2);
            app.hundredMHzButton.Text = '100 MHz';
            app.twentyMHzButton = uiradiobutton(app.BandwidthButtonGroup_2);
            app.twentyMHzButton.Text = '20 MHz';
            app.twentyfiveMHzButton = uiradiobutton(app.BandwidthButtonGroup_2);
            app.twentyfiveMHzButton.Text = '25 MHz';
            app.seventyMHzButton = uiradiobutton(app.BandwidthButtonGroup_2);
            app.seventyMHzButton.Text = '70 MHz';
            app.Adjust5GNRparametersforMCCLabel = uilabel(app.UIFigure);
            app.Adjust5GNRparametersforMCCLabel.Text = 'Adjust 5G NR parameters (for MCC):';
            app.SpatialstreamsButtonGroup_2 = uibuttongroup(app.UIFigure);
            app.SpatialstreamsButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @SpatialstreamsButtonGroup_2SelectionChanged, true);
            app.SpatialstreamsButtonGroup_2.Title = 'Spatial streams:';
            app.SISOButton_2 = uiradiobutton(app.SpatialstreamsButtonGroup_2);
            app.SISOButton_2.Text = 'SISO';
            app.MIMO2x2Button_2 = uiradiobutton(app.SpatialstreamsButtonGroup_2);
            app.MIMO2x2Button_2.Text = 'MIMO 2x2';
            app.MIMO3x3Button_2 = uiradiobutton(app.SpatialstreamsButtonGroup_2);
            app.MIMO3x3Button_2.Text = 'MIMO 3x3';
            app.MIMO4x4Button_2 = uiradiobutton(app.SpatialstreamsButtonGroup_2);
            app.MIMO4x4Button_2.Text = 'MIMO 4x4';
            app.MIMO6x6Button_2 = uiradiobutton(app.SpatialstreamsButtonGroup_2);
            app.MIMO6x6Button_2.Text = 'MIMO 6x6';
            app.MIMO8x8Button_2 = uiradiobutton(app.SpatialstreamsButtonGroup_2);
            app.MIMO8x8Button_2.Text = 'MIMO 8x8';
            app.MIMO8x8Button_2.Value = true;
            app.ModulationButtonGroup_2 = uibuttongroup(app.UIFigure);
            app.ModulationButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ModulationButtonGroup_2SelectionChanged, true);
            app.ModulationButtonGroup_2.Title = 'Modulation:';
            app.QPSKButton_2 = uiradiobutton(app.ModulationButtonGroup_2);
            app.QPSKButton_2.Text = 'QPSK';
            app.QAM16Button_2 = uiradiobutton(app.ModulationButtonGroup_2);
            app.QAM16Button_2.Text = 'QAM-16';
            app.QAM64Button_2 = uiradiobutton(app.ModulationButtonGroup_2);
            app.QAM64Button_2.Text = 'QAM-64';
            app.QAM256Button_2 = uiradiobutton(app.ModulationButtonGroup_2);
            app.QAM256Button_2.Text = 'QAM-256';
            app.QAM256Button_2.Value = true;
            app.IoTprocessorfrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.IoTprocessorfrequencyEditFieldLabel.Text = 'IoT processor frequency:';
            app.IoTprocessorfrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.IoTprocessorfrequencyEditField.Limits = [1 100];
            app.IoTprocessorfrequencyEditField.ValueDisplayFormat = '%.0f';
            app.IoTprocessorfrequencyEditField.Value = 8;
            app.x36GHzLabel = uilabel(app.UIFigure);
            app.x36GHzLabel.Text = 'x 3.6 GHz';
            app.EdgeserverprocessorfrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.EdgeserverprocessorfrequencyEditFieldLabel.Text = 'Edge server processor frequency:';
            app.EdgeserverprocessorfrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.EdgeserverprocessorfrequencyEditField.Limits = [1 Inf];
            app.EdgeserverprocessorfrequencyEditField.ValueDisplayFormat = '%.0f';
            app.EdgeserverprocessorfrequencyEditField.Value = 16;
            app.x30GHzLabel = uilabel(app.UIFigure);
            app.x30GHzLabel.Text = 'x 3.0 GHz';
            app.CloudserverprocessorfrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.CloudserverprocessorfrequencyEditFieldLabel.Text = 'Cloud server processor frequency:';
            app.CloudserverprocessorfrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.CloudserverprocessorfrequencyEditField.Limits = [1 Inf];
            app.CloudserverprocessorfrequencyEditField.ValueDisplayFormat = '%.0f';
            app.CloudserverprocessorfrequencyEditField.Value = 96;
            app.x47GHzLabel = uilabel(app.UIFigure);
            app.x47GHzLabel.Text = 'x 4.7 GHz';
            app.OutputresultsLabel = uilabel(app.UIFigure);
            app.OutputresultsLabel.Text = 'Output results:';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Workload [Mb]:'};
            app.UITable.RowName = {};
            app.OverallresponsetimemsLabel = uilabel(app.UIFigure);
            app.OverallresponsetimemsLabel.Text = 'Overall response time [ms]:';
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'IoT'; 'MEC'; 'MCC'};
            app.UITable2.RowName = {};
            app.OverallpowerconsumptionkWhLabel = uilabel(app.UIFigure);
            app.OverallpowerconsumptionkWhLabel.Text = 'Overall power consumption [kWh]:';
            app.UITable2_2 = uitable(app.UIFigure);
            app.UITable2_2.ColumnName = {'IoT'; 'MEC'; 'MCC'};
            app.UITable2_2.RowName = {};
            app.BitrateMbpsLabel = uilabel(app.UIFigure);
            app.BitrateMbpsLabel.Text = 'Bit rate [Mbps]:';
            app.UITable2_3 = uitable(app.UIFigure);
            app.UITable2_3.ColumnName = {'WiFi'; '5G NR'};
            app.UITable2_3.RowName = {};
            app.UIAxesTIot = uiaxes(app.UIFigure);
            title(app.UIAxesTIot, 'IoT model response time')
            ylabel(app.UIAxesTIot, 'Time [ms]')
            zlabel(app.UIAxesTIot, 'Z')
            app.UIAxesEIot = uiaxes(app.UIFigure);
            title(app.UIAxesEIot, 'IoT model power consumption')
            ylabel(app.UIAxesEIot, 'Power Consumption [kWh]')
            zlabel(app.UIAxesEIot, 'Z')
            app.UIAxesTEdge = uiaxes(app.UIFigure);
            title(app.UIAxesTEdge, 'MEC model response time')
            ylabel(app.UIAxesTEdge, 'Time [ms]')
            zlabel(app.UIAxesTEdge, 'Z')
            app.UIAxesEEdge = uiaxes(app.UIFigure);
            title(app.UIAxesEEdge, 'MEC model power consumption')
            ylabel(app.UIAxesEEdge, 'Power Consumption [kWh]')
            zlabel(app.UIAxesEEdge, 'Z')
            app.UIAxesTCloud = uiaxes(app.UIFigure);
            title(app.UIAxesTCloud, 'MCC model response time')
            ylabel(app.UIAxesTCloud, 'Time [ms]')
            zlabel(app.UIAxesTCloud, 'Z')
            app.UIAxesECloud = uiaxes(app.UIFigure);
            title(app.UIAxesECloud, 'MCC model power consumption')
            ylabel(app.UIAxesECloud, 'Power Consumption [kWh]')
            zlabel(app.UIAxesECloud, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ResponseTimeAndEnergyConsump

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end